classdef StringCrossSearchApp_v12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        StringCrossSearchAppUIFigure    matlab.ui.Figure
        DevelopedbyBagratAbramianPetrosianusingMATLABR2019b2020Label  matlab.ui.control.Label
        v12Label                        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        BasicOptionsTab                 matlab.ui.container.Tab
        NormalizevectorsButton          matlab.ui.control.Button
        RuncrosssearchButton            matlab.ui.control.Button
        SelectoutputfolderButton        matlab.ui.control.Button
        HelpButton                      matlab.ui.control.Button
        CurrentdatavectorfileEditFieldLabel  matlab.ui.control.Label
        CurrentdatavectorfileEditField  matlab.ui.control.EditField
        CurrentsearchvectorfileEditFieldLabel  matlab.ui.control.Label
        CurrentsearchvectorfileEditField  matlab.ui.control.EditField
        CurrentoutputdirectoryEditFieldLabel  matlab.ui.control.Label
        CurrentoutputdirectoryEditField  matlab.ui.control.EditField
        CurrentdatatagfileEditFieldLabel  matlab.ui.control.Label
        CurrentdatatagfileEditField     matlab.ui.control.EditField
        CurrentsearchtagfileEditFieldLabel  matlab.ui.control.Label
        CurrentsearchtagfileEditField   matlab.ui.control.EditField
        ImportoptionsPanel              matlab.ui.container.Panel
        DetectcorrectspecialcharactersininputfilesCheckBox  matlab.ui.control.CheckBox
        ImportdatavectrorButton         matlab.ui.control.Button
        ImportsearchvectorButton        matlab.ui.control.Button
        ImportdatatagsButton            matlab.ui.control.Button
        CleardatatagsButton             matlab.ui.control.Button
        ImportsearchtagsButton          matlab.ui.control.Button
        ClearsearchtagsButton           matlab.ui.control.Button
        CleardatavectorButton           matlab.ui.control.Button
        ClearsearchvectorButton         matlab.ui.control.Button
        ExportoptionsPanel              matlab.ui.container.Panel
        ExporttotalsearchvaluesvectorperdatapointCheckBox  matlab.ui.control.CheckBox
        ExporttotalsearchvaluesvectorpersearchpointCheckBox  matlab.ui.control.CheckBox
        ExportcombineddatamatrixwithtotalsCheckBox  matlab.ui.control.CheckBox
        ExportcombinedsearchmatrixwithtotalsCheckBox  matlab.ui.control.CheckBox
        TotalsearchvaluesvectorpdpfilenameEditFieldLabel  matlab.ui.control.Label
        TotalsearchvaluesvectorpdpfilenameEditField  matlab.ui.control.EditField
        TotalsearchvaluesvectorpspfilenameEditFieldLabel  matlab.ui.control.Label
        TotalsearchvaluesvectorpspfilenameEditField  matlab.ui.control.EditField
        CombineddatamatrixfilenameEditFieldLabel  matlab.ui.control.Label
        CombineddatamatrixfilenameEditField  matlab.ui.control.EditField
        CombinedsearchmatrixfilenameEditFieldLabel  matlab.ui.control.Label
        CombinedsearchmatrixfilenameEditField  matlab.ui.control.EditField
        SearchvectorconflictsPanel      matlab.ui.container.Panel
        CheckseachvectorconflictsredundanciesButton  matlab.ui.control.Button
        SearchvectorconflictsTextArea   matlab.ui.control.TextArea
        UponidenticalsearchentriesButtonGroup  matlab.ui.container.ButtonGroup
        KeepallsearchentriesButton      matlab.ui.control.RadioButton
        KeeponlyfirstButton             matlab.ui.control.RadioButton
        KeeponlylastButton              matlab.ui.control.RadioButton
        AdvancedOptionsTab              matlab.ui.container.Tab
        OtherexportoptionsPanel         matlab.ui.container.Panel
        ExportverificationmatrixCheckBox  matlab.ui.control.CheckBox
        ExportfullmatrixCheckBox        matlab.ui.control.CheckBox
        VerificationmatrixfilenameEditFieldLabel  matlab.ui.control.Label
        VerificationmatrixfilenameEditField  matlab.ui.control.EditField
        FullmatrixfilenameEditFieldLabel  matlab.ui.control.Label
        FullmatrixfilenameEditField     matlab.ui.control.EditField
        FullmatrixexportoptionsButtonGroup  matlab.ui.container.ButtonGroup
        OnlydataxsearchsheetButton      matlab.ui.control.RadioButton
        OnlysearchxdatasheetButton      matlab.ui.control.RadioButton
        BothsheetsButton                matlab.ui.control.RadioButton
        NormalizationoptionsPanel       matlab.ui.container.Panel
        LowercasevectorsCheckBox        matlab.ui.control.CheckBox
        NormalizediacriticsCheckBox     matlab.ui.control.CheckBox
        SpecialcharacterscorrectionoptionsPanel  matlab.ui.container.Panel
        LatincharactersCheckBox         matlab.ui.control.CheckBox
        GreekcharactersCheckBox         matlab.ui.control.CheckBox
        CyriliccharactersCheckBox       matlab.ui.control.CheckBox
        GeneralsymbolsCheckBox          matlab.ui.control.CheckBox
        AdvancedcrosssearchoptionsPanel  matlab.ui.container.Panel
        RemovefalsepositivesCheckBox    matlab.ui.control.CheckBox
    end

    properties (Access = public)
        data_vector
        search_vector
        conflict_report
        output_path
        data_tags
        search_tags
        spec_char_identifier
        spec_char_correction_vector_input_symbols
        spec_char_correction_vector_input_latin
        spec_char_correction_vector_input_greek
        spec_char_correction_vector_input_cyrilic
        spec_char_correction_vector_output_symbols
        spec_char_correction_vector_output_latin
        spec_char_correction_vector_output_greek
        spec_char_correction_vector_output_cyrilic
        diacritic_normalization_input_lowercase
        diacritic_normalization_input_uppercase
        diacritic_normalization_output_lowercase
        diacritic_normalization_output_uppercase
    end
    
    methods (Access = public)
        
        function string_search_matrix = search_in_string(app,data_input,search_input)
            % This function was provided by David Abramian (https://github.com/DavidAbramian)
            % Function for searching strings in other strings
            % data_input refers to the string vector in which we wish to search
            % search_input refers to the string vector with which we are searching
            % both data_input and search_input have to be in the form of vertical vector cell arrays
            string_search_matrix = cell2mat( cellfun(@(t)contains(data_input,t), search_input, 'UniformOutput', false )' );
        end
        function systemic_character_cell_change = systemic_replacement(app,original_cell,original_characters_to_change,new_characters)
            % Function to perform various consecutive changes to each
            % elements of a cell array.
            % original_cell is the cell array on which we intend to apply
            % the changes
            % original_characters_to_change is an array of all the different characters you wish to change
            % new_characters is what each element of original_characters_to_change is going to be changed into
            % All input elements have to be in the form of vertical vector cell arrays
            m = 1;
            n = 1;
            % m rotates through original_cell
            % n rotates through original_characters_to_change and new_characters
            if not(size(original_characters_to_change,1) == size(new_characters,1))
                errordlg('Discrepancy in size between "orginal characters" vector to change and "new characters" vector. Please contact the developer with your "Special characters correction options" settings when the error appeared.','Error');
            else
                if size(original_cell,1) == 1
                    original_cell_as_char = char(original_cell);
                    while n <= length(original_characters_to_change)
                        original_cell_as_char = strrep(original_cell_as_char,original_characters_to_change(n),new_characters(n));
                        n = n + 1;
                    end
                    systemic_character_cell_change = cellstr(original_cell_as_char);
                else
                    % Blank cell array on which to write the new, changed elements
                    new_cell_array = cell(size(original_cell,1),1);
                    while m <= size(original_cell,1)
                        % Take an element of original_cell and turn it into
                        % a character vector to be operated on
                        original_cell_as_char = char(original_cell(m));
                        while n <= length(original_characters_to_change)
                            original_cell_as_char = strrep(original_cell_as_char,original_characters_to_change(n),new_characters(n));
                            n = n + 1;
                        end
                        new_cell_array(m) = cellstr(original_cell_as_char);
                        % Reset n
                        n = 1;
                        m = m + 1;
                    end
                    systemic_character_cell_change = new_cell_array;
                end
            end
        end
        function reqursive_tags_question_output = recursive_tags_quetion(app,vector_name,vector_length)
            % Function for "Default tags options" == Ask
            recursive_tags_question_answer = questdlg("Which tags would you like to apply to this vector?","Tags Question",'Custom-defined name','Vector file name','Choose tags file','Vector file name');
            switch recursive_tags_question_answer
                case 'Custom-defined name'
                    if isprop(app, 'CustomtagnameEditField.Value') && isempty(app.CustomtagnameEditField.Value)
                        tags_filename = cell(vector_length,1);
                        tags_filename(:) = {vector_name};
                        reqursive_tags_question_output = tags_filename;
                        msgbox("Custom name field empty. Tags defaulted to vector's filename.",'Warning','warn');
                    else
                        tags_filename = cell(vector_length,1);
                        tags_filename(:) = cellstr(app.CustomtagnameEditField.Value);
                        reqursive_tags_question_output = tags_filename;
                    end
                case 'Choose tags file'
                    [f_tags,p_tags] = uigetfile({'*.txt','Text files';'*.csv','CSV';'*','All file types'});
                    if f_tags == 0
                        tags_name = "No file chosen";
                    elseif (ischar(p_tags))
                        tags_name = fullfile(p_tags,f_tags);
                    else
                        tags_name = f_tags;
                    end
                    if tags_name == "No file chosen"
                        tags_filename = cell(vector_length,1);
                        tags_filename(:) = {vector_name};
                        tags_file = tags_filename;
                        msgbox("No file chosen. Tags defaulted to vector's filename.",'Warning','warn');
                    else
                        tags_file = importdata(tags_name,"\n");
                    end
                    if ischar(tags_file)+isstring(tags_file)+iscell(tags_file)==0
                        errordlg('The tags file has not been imported as a cell or string array.','Error');
                        reqursive_tags_question_output = recursive_tags_quetion(app,vector_name,vector_length);
                    elseif not(size(tags_file,2) == 1)
                        errordlg('The tags file must be a vertical vector.','Error');
                        reqursive_tags_question_output = recursive_tags_quetion(app,vector_name,vector_length);
                    elseif not(size(tags_file,1) == vector_length)
                        errordlg('The tags file must have the same number of rows as its corresponding vector.','Error');
                        reqursive_tags_question_output = recursive_tags_quetion(app,vector_name,vector_length);
                    else
                        reqursive_tags_question_output = tags_file;
                    end
                otherwise
                    tags_filename = cell(vector_length,1);
                    tags_filename(:) = {vector_name};
                    reqursive_tags_question_output = tags_filename;
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.spec_char_identifier = {'Ã';'Â';'Å';'ã';'â';'â';'å';'Î';'Ï';'î';'ï';'Ð';'ð';'Ñ';'ñ'}; % These characters have been shown to appear systemically when importing txt to MATLAB, thus we only need to look for them to identify special characters in the input file
            % Correlation vectors, add more if necessary (don't forget to update app properties, checkboxes in "Special characters' correction options", and the checking in the various import checks when looking if all checking is left blank)
            app.spec_char_correction_vector_input_symbols = {'Âº';'Âª';'Â¡';'Â¿';'â‚¬';'Â¬';'Â·';'Â´';'Â¨';'';'';'<Â ';'>Â ';'Â¢';'Â£';'Â¤';'Â¥';'Â¦';'Â§';'Â©';'Â«';'Â®';'Â¯';'Â±';'Â²';'Â³';'Âµ';'Â¶';'Â·';'Â¸';'Â»';'Â¼';'Â½';'Â¾';'Ã—';'Ã·';'â„¢';'Â§';'â€¦';'Ãž'};
            app.spec_char_correction_vector_output_symbols = {'º';'ª';'¡';'¿';'€';'¬';'·';'´';'¨';',';';';'< ';'> ';'¢';'£';'¤';'¥';'¦';'§';'©';'«';'®';'¯';'±';'²';'³';'µ';'¶';'·';'¸';'»';'¼';'½';'¾';'×';'÷';'™';'§';'…';'Þ'};
            app.spec_char_correction_vector_input_latin = {'Ã';'Ã„';'Ã€';'Ã‚';'Ãƒ';'Ã‰';'Ã‹';'Ãˆ';'ÃŠ';'Ã';'Ã';'ÃŒ';'ÃŽ';'Ã“';'Ã–';'Ã’';'Ã”';'Ã•';'Ãš';'Ãœ';'Ã™';'Ã›';'Ã‘';'Ã‡';'Ã…';'Ã†';'Ã';'Ã˜';'Ã';'Å’';
                'Ã¡';'Ã¤';'Ã ';'Ã¢';'Ã£';'Ã©';'Ã«';'Ã¨';'Ãª';'Ã­';'Ã¯';'Ã¬';'Ã®';'Ã³';'Ã¶';'Ã²';'Ã´';'Ãµ';'Ãº';'Ã¼';'Ã¹';'Ã»';'Ã±';'Ã§';'Ã¥';'Ã¦';'Ã°';'Ã¸';'Ã½';'Å“';'ÃŸ'};
            app.spec_char_correction_vector_output_latin = {'Á';'Ä';'À';'Â';'Ã';'É';'Ë';'È';'Ê';'Í';'Ï';'Ì';'Î';'Ó';'Ö';'Ò';'Ô';'Õ';'Ú';'Ü';'Ù';'Û';'Ñ';'Ç';'Å';'Æ';'Ð';'Ø';'Ý';'Œ';
                'á';'ä';'à';'â';'ã';'é';'ë';'è';'ê';'í';'ï';'ì';'î';'ó';'ö';'ò';'ô';'õ';'ú';'ü';'ù';'û';'ñ';'ç';'å';'æ';'ð';'ø';'ý';'œ';'ß'};
            app.spec_char_correction_vector_input_greek = {'Î†';'Î‡';'Îˆ';'Î‰';'ÎŠ';'ÎŒ';'ÎŽ';'Î';'Î';'Î‘';'Î’';'Î“';'Î”';'Î•';'Î–';'Î—';'Î˜';'Î™';'Îš';'Î›';'Îœ';'Î';'Îž';'ÎŸ';'Î ';'Î¡';'Î£';'Î¤';'Î¥';'Î¦';'Î§';'Î¨';'Î©';'Îª';'Î«';
                'Î¬';'Î­';'Î®';'Î¯';'Î°';'Î±';'Î²';'Î³';'Î´';'Îµ';'Î¶';'Î·';'Î¸';'Î¹';'Îº';'Î»';'Î¼';'Î½';'Î¾';'Î¿';'Ï€';'Ï';'Ï‚';'Ïƒ';'Ï„';'Ï…';'Ï†';'Ï‡';'Ïˆ';'Ï‰';'ÏŠ';'Ï‹';'ÏŒ';'Ï';'ÏŽ';'Ï';'Ï‘'};
            app.spec_char_correction_vector_output_greek = {'Ά';'·';'Έ';'Ή';'Ί';'Ό';'Ύ';'Ώ';'ΐ';'Α';'Β';'Γ';'Δ';'Ε';'Ζ';'Η';'Θ';'Ι';'Κ';'Λ';'Μ';'Ν';'Ξ';'Ο';'Π';'Ρ';'Σ';'Τ';'Υ';'Φ';'Χ';'Ψ';'Ω';'Ϊ';'Ϋ';
                'ά';'έ';'ή';'ί';'ΰ';'α';'β';'γ';'δ';'ε';'ζ';'η';'θ';'ι';'κ';'λ';'μ';'ν';'ξ';'ο';'π';'ρ';'ς';'σ';'τ';'υ';'φ';'χ';'ψ';'ω';'ϊ';'ϋ';'ό';'ύ';'ώ';'ϐ';'ϑ'};
            app.spec_char_correction_vector_input_cyrilic = {'Ð';'Ð‚';'Ðƒ';'Ð„';'Ð…';'Ð†';'Ð‡';'Ðˆ';'Ð‰';'ÐŠ';'Ð‹';'ÐŒ';'ÐŽ';'Ð';'Ð';'Ð‘';'Ð’';'Ð“';'Ð”';'Ð•';'Ð–';'Ð—';'Ð˜';'Ð™';'Ðš';'Ð›';'Ðœ';'Ð';'Ðž';'ÐŸ';'Ð ';'Ð¡';'Ð¢';'Ð£';'Ð¤';'Ð¥';'Ð¦';'Ð§';'Ð¨';'Ð©';'Ðª';'Ð«';'Ð¬';'Ð­';'Ð®';'Ð¯';
                'Ð°';'Ð±';'Ð²';'Ð³';'Ð´';'Ðµ';'Ð¶';'Ð·';'Ð¸';'Ð¹';'Ðº';'Ð»';'Ð¼';'Ð½';'Ð¾';'Ð¿';'Ñ€';'Ñ';'Ñ‚';'Ñƒ';'Ñ„';'Ñ…';'Ñ†';'Ñ‡';'Ñˆ';'Ñ‰';'ÑŠ';'Ñ‹';'ÑŒ';'Ñ';'ÑŽ';'Ñ';'Ñ‘';'Ñ’';'Ñ“';'Ñ”';'Ñ•';'Ñ–';'Ñ—';'Ñ˜';'Ñ™';'Ñš';'Ñ›';'Ñœ';'Ñž';'ÑŸ'};
            app.spec_char_correction_vector_output_cyrilic = {'Ё';'Ђ';'Ѓ';'Є';'Ѕ';'І';'Ї';'Ј';'Љ';'Њ';'Ћ';'Ќ';'Ў';'Џ';'А';'Б';'В';'Г';'Д';'Е';'Ж';'З';'И';'Й';'К';'Л';'М';'Н';'О';'П';'Р';'С';'Т';'У';'Ф';'Х';'Ц';'Ч';'Ш';'Щ';'Ъ';'Ы';'Ь';'Э';'Ю';'Я';
                'а';'б';'в';'г';'д';'е';'ж';'з';'и';'й';'к';'л';'м';'н';'о';'п';'р';'с';'т';'у';'ф';'х';'ц';'ч';'ш';'щ';'ъ';'ы';'ь';'э';'ю';'я';'ё';'ђ';'ѓ';'є';'ѕ';'і';'ї';'ј';'љ';'њ';'ћ';'ќ';'ў';'џ'};
            % Diacritic normalization, not made with German in mind, thus Ä, Ö, and Ü (and their lowercase equivalents) are simply rendered as A, O, and U; rather than AE, OE, and UE.
            app.diacritic_normalization_input_lowercase = {'á';'ä';'à';'â';'ã';'å';'é';'ë';'è';'ê';'í';'ï';'ì';'î';'ó';'ö';'ò';'ô';'õ';'ú';'ü';'ù';'û'};
            app.diacritic_normalization_input_uppercase = {'Á';'Ä';'À';'Â';'Ã';'Å';'É';'Ë';'È';'Ê';'Í';'Ï';'Ì';'Î';'Ó';'Ö';'Ò';'Ô';'Õ';'Ú';'Ü';'Ù';'Û'};
            app.diacritic_normalization_output_lowercase = {'a';'a';'a';'a';'a';'a';'e';'e';'e';'e';'i';'i';'i';'i';'o';'o';'o';'o';'o';'u';'u';'u';'u'};
            app.diacritic_normalization_output_uppercase = {'A';'A';'A';'A';'A';'A';'E';'E';'E';'E';'I';'I';'I';'I';'O';'O';'O';'O';'O';'U';'U';'U';'U'};
        end

        % Button pushed function: ImportdatavectrorButton
        function ImportdatavectrorButtonPushed(app, event)
            [f_data_vector,p_data_vector] = uigetfile({'*.txt','Text files';'*.csv','CSV';'*','All file types'});
            if f_data_vector == 0
                return
            end
            if (ischar(p_data_vector))
                data_vector_name = fullfile(p_data_vector,f_data_vector);
            else
                data_vector_name = f_data_vector;
            end
            new_data_vector = importdata(data_vector_name,"\n");
            if (isprop(app, 'data_tags') && ~isempty(app.data_tags))
                if length(app.data_tags) == length(new_data_vector)
                    data_tags_question = questdlg('There is a data tags file loaded of the same length as the new data vector. Do you want to keep it?','Question','Yes','No','Yes');
                    switch data_tags_question
                        case 'No'
                            app.data_tags = {};
                            app.CurrentdatatagfileEditField.Value = "";
                        otherwise
                    end
                else
                    app.data_tags = {};
                    app.CurrentdatatagfileEditField.Value = "";
                    msgbox("The previous data tags length doesn't match the new data vector, and thus has been removed.",'Warning','warn');
                end
            end
            if ischar(new_data_vector)+isstring(new_data_vector)+iscell(new_data_vector)==0
                errordlg('The data vector has not been imported as a cell or string array.','Error');
                return
            end
            size_data_vector = size(new_data_vector);
            if not(size_data_vector(2) == 1)
                errordlg('The data vector must be a vertical vector.','Error');
                return
            end
            if app.DetectcorrectspecialcharactersininputfilesCheckBox.Value == 1
                data_vector_spec_char_matrix = search_in_string(app, new_data_vector,app.spec_char_identifier);
                if not(sum(data_vector_spec_char_matrix,"all") == 0)
                    data_spec_char_question = questdlg('It seems there are special characters in your data vector. Do you want to keep it, discard it, or try to correct it? (If your input file is a csv, please select "Keep", as it should not cause any issues)','Question','Keep','Discard','Correct','Keep');
                    switch data_spec_char_question
                        case 'Discard'
                        return
                        case 'Correct'
                            spec_char_correction_vector_input = {};
                            spec_char_correction_vector_output = {};
                            if app.LatincharactersCheckBox.Value + app.GreekcharactersCheckBox.Value + app.CyriliccharactersCheckBox.Value + app.GeneralsymbolsCheckBox.Value == 0
                                msgbox("No special characters' type correction chosen. Data vector imported as-is.",'Warning','warn');
                            else
                                if app.GeneralsymbolsCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_symbols);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_symbols);
                                end
                                if app.LatincharactersCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_latin);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_latin);
                                end
                                if app.GreekcharactersCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_greek);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_greek);
                                end
                                if app.CyriliccharactersCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_cyrilic);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_cyrilic);
                                end
                                new_data_vector = systemic_replacement(app,new_data_vector,spec_char_correction_vector_input,spec_char_correction_vector_output);
                                msgbox('Special characters corrected.');
                            end
                        otherwise
                    end
                end
            end
            app.data_vector = new_data_vector;
            app.CurrentdatavectorfileEditField.Value = f_data_vector;
        end

        % Button pushed function: CleardatavectorButton
        function CleardatavectorButtonPushed(app, event)
            app.data_vector = {};
            app.CurrentdatavectorfileEditField.Value = "";
        end

        % Button pushed function: ImportsearchvectorButton
        function ImportsearchvectorButtonPushed(app, event)
            [f_search_vector,p_search_vector] = uigetfile({'*.txt','Text files';'*.csv','CSV';'*','All file types'});
            if f_search_vector == 0
                return
            end
            if (ischar(p_search_vector))
                search_vector_name = fullfile(p_search_vector,f_search_vector);
            else
                search_vector_name = f_search_vector;
            end
            new_search_vector = importdata(search_vector_name,"\n");
            if (isprop(app, 'search_tags') && ~isempty(app.search_tags))
                if length(app.search_tags) == length(new_search_vector)
                    search_tags_question = questdlg('There is a search tags file loaded of the same length as the new search vector. Do you want to keep it?','Question','Yes','No','Yes');
                    switch search_tags_question
                        case 'No'
                            app.search_tags = {};
                            app.CurrentsearchtagfileEditField.Value = "";
                        otherwise
                    end
                else
                    app.search_tags = {};
                    app.CurrentsearchtagfileEditField.Value = "";
                    msgbox("The previous search tags length doesn't match the new search vector, and thus has been removed.",'Warning','warn');
                end
            end
            if ischar(new_search_vector)+isstring(new_search_vector)+iscell(new_search_vector)==0
                errordlg('The search vector has not been imported as a cell or string array.','Error');
                return
            end
            size_search_vector = size(new_search_vector);
            if not(size_search_vector(2) == 1)
                errordlg('The search vector must be a vertical vector.','Error');
                return
            end
            if app.DetectcorrectspecialcharactersininputfilesCheckBox.Value == 1
                search_vector_spec_char_matrix = search_in_string(app, new_search_vector,app.spec_char_identifier);
                if not(sum(search_vector_spec_char_matrix,"all") == 0)
                    search_spec_char_question = questdlg('It seems there are special characters in your search vector. Do you want to keep it, discard it, or try to correct it? (If your input file is a csv, please select "Keep", as it should not cause any issues)','Question','Keep','Discard','Correct','Keep');
                    switch search_spec_char_question
                        case 'Discard'
                        return
                        case 'Correct'
                            spec_char_correction_vector_input = {};
                            spec_char_correction_vector_output = {};
                            if app.LatincharactersCheckBox.Value + app.GreekcharactersCheckBox.Value + app.CyriliccharactersCheckBox.Value + app.GeneralsymbolsCheckBox.Value == 0
                                msgbox("No special characters' type correction chosen. Search vector imported as-is.",'Warning','warn');
                            else
                                if app.GeneralsymbolsCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_symbols);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_symbols);
                                end
                                if app.LatincharactersCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_latin);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_latin);
                                end
                                if app.GreekcharactersCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_greek);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_greek);
                                end
                                if app.CyriliccharactersCheckBox.Value == 1
                                    spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_cyrilic);
                                    spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_cyrilic);
                                end
                                new_search_vector = systemic_replacement(app,new_search_vector,spec_char_correction_vector_input,spec_char_correction_vector_output);
                                msgbox('Special characters corrected.');
                            end
                        otherwise
                    end
                end
            end
            app.search_vector = new_search_vector;
            app.CurrentsearchvectorfileEditField.Value = f_search_vector;
        end

        % Button pushed function: ClearsearchvectorButton
        function ClearsearchvectorButtonPushed(app, event)
            app.search_vector = {};
            app.CurrentsearchvectorfileEditField.Value = "";
        end

        % Button pushed function: 
        % CheckseachvectorconflictsredundanciesButton
        function CheckseachvectorconflictsredundanciesButtonPushed(app, event)
            if not(isprop(app, 'search_vector') && ~isempty(app.search_vector))
                errordlg('No search vector imported.','Error');
                return
            end
            if app.KeepallsearchentriesButton.Value == 0 && not(length(app.search_vector) == length(unique(app.search_vector)))
                % This method preserves the search tags as they are
                search_vector_squared_matrix = search_in_string(app,app.search_vector,app.search_vector);
                search_vector_conflictmatrix = search_vector_squared_matrix - eye(length(app.search_vector));
                [r_conflict,c_conflict] = find(search_vector_conflictmatrix);
                % l cycles through r_conflict and c_conflict
                l = 1;
                while l <= length(r_conflict)
                    if search_vector_squared_matrix(c_conflict(l),r_conflict(l)) == 1 && search_vector_squared_matrix(r_conflict(l),c_conflict(l)) == 1
                        if app.KeeponlylastButton.Value == 1
                            search_vector_squared_matrix(r_conflict(l),:) = 0;
                        else
                            search_vector_squared_matrix(c_conflict(l),:) = 0;
                        end
                    end
                    l = l + 1;
                end
                nonrepeated_search_logical = logical(sum(search_vector_squared_matrix,2));
                app.search_vector = app.search_vector(nonrepeated_search_logical);
                if (isprop(app, 'search_tags') && ~isempty(app.search_tags))
                    app.search_tags = app.search_tags(nonrepeated_search_logical);
                end
            end
            search_vector_squared_matrix = search_in_string(app,app.search_vector,app.search_vector);
            search_vector_conflictmatrix = search_vector_squared_matrix - eye(length(app.search_vector));
            number_of_conflicts_nostring = sum(search_vector_conflictmatrix,'all');
            number_of_conflicts_string = string (number_of_conflicts_nostring);
            if number_of_conflicts_nostring == 0
                name_of_conflicts = join(['No conflicts detected.']);
            elseif number_of_conflicts_nostring == 1
                number_of_conflicts = join(['There is',number_of_conflicts_string,'conflict']);
                number_of_conflicts = join(number_of_conflicts);
                [r_conflict,c_conflict] = find(search_vector_conflictmatrix);
                conflicts_string = cell(number_of_conflicts_nostring,1);
                conflicts_string(:) = {'conflicts with'};
                name_of_conflicts_cell = [app.search_vector(c_conflict),conflicts_string,app.search_vector(r_conflict)];
                name_of_conflicts_string_matrix = string (name_of_conflicts_cell);
                name_of_conflicts = join (name_of_conflicts_string_matrix);
                name_of_conflicts = [number_of_conflicts;name_of_conflicts];
            else
                number_of_conflicts = join(['There are',number_of_conflicts_string,'conflicts']);
                number_of_conflicts = join(number_of_conflicts);
                [r_conflict,c_conflict] = find(search_vector_conflictmatrix);
                conflicts_string = cell(number_of_conflicts_nostring,1);
                conflicts_string(:) = {'conflicts with'};
                name_of_conflicts_cell = [app.search_vector(c_conflict),conflicts_string,app.search_vector(r_conflict)];
                name_of_conflicts_string_matrix = string (name_of_conflicts_cell);
                name_of_conflicts = join (name_of_conflicts_string_matrix);
                name_of_conflicts = [number_of_conflicts;name_of_conflicts];
            end
            app.conflict_report = name_of_conflicts;
            app.SearchvectorconflictsTextArea.Value = app.conflict_report;
        end

        % Button pushed function: ImportdatatagsButton
        function ImportdatatagsButtonPushed(app, event)
            if not(isprop(app, 'data_vector') && ~isempty(app.data_vector))
                errordlg('To apply data tags you have to first import a data vector.','Error');
            else
                [f_data_tags,p_data_tags] = uigetfile({'*.txt','Text files';'*.csv','CSV';'*','All file types'});
                if f_data_tags == 0
                    return
                end
                if (ischar(p_data_tags))
                    data_tags_name = fullfile(p_data_tags,f_data_tags);
                else
                    data_tags_name = f_data_tags;
                end
                app.data_tags = importdata(data_tags_name,"\n");
                app.CurrentdatatagfileEditField.Value=f_data_tags;
                if not(length(app.data_tags) == length(app.data_vector))
                    app.data_tags = {};
                    app.CurrentdatatagfileEditField.Value = "";
                    errordlg('The data tags file has to be the same length as the data vector.','Error');
                end
                if ischar(app.data_tags)+isstring(app.data_tags)+iscell(app.data_tags) == 0
                    app.data_tags = {};
                    app.CurrentdatatagfileEditField.Value = "";
                    errordlg('The data tags has not been imported as a cell or string array.','Error');
                end
                size_data_tags = size(app.data_tags);
                if not(size_data_tags(2) == 1)
                    app.data_tags = {};
                    app.CurrentdatatagfileEditField.Value = "";
                    errordlg('The data tags must be a vertical vector.','Error');
                    return
                end
                if app.DetectcorrectspecialcharactersininputfilesCheckBox.Value == 1
                    data_tags_spec_char_matrix = search_in_string(app,app.data_tags,app.spec_char_identifier);
                    if not(sum(data_tags_spec_char_matrix,"all") == 0)
                        search_spec_char_question = questdlg('It seems there are special characters in your data tags. Do you want to keep it, discard it, or try to correct it? (If your input file is a csv, please select "Keep", as it should not cause any issues)','Question','Keep','Discard','Correct','Keep');
                        switch search_spec_char_question
                            case 'Discard'
                                app.data_tags = {};
                                app.CurrentdatatagfileEditField.Value = "";
                                return
                            case 'Correct'
                                spec_char_correction_vector_input = {};
                                spec_char_correction_vector_output = {};
                                if app.LatincharactersCheckBox.Value + app.GreekcharactersCheckBox.Value + app.CyriliccharactersCheckBox.Value + app.GeneralsymbolsCheckBox.Value == 0
                                    msgbox("No special characters' type correction chosen. Data tags imported as-is.",'Warning','warn');
                                else
                                    if app.GeneralsymbolsCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_symbols);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_symbols);
                                    end
                                    if app.LatincharactersCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_latin);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_latin);
                                    end
                                    if app.GreekcharactersCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_greek);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_greek);
                                    end
                                    if app.CyriliccharactersCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_cyrilic);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_cyrilic);
                                    end
                                    app.data_tags = systemic_replacement(app,app.data_tags,spec_char_correction_vector_input,spec_char_correction_vector_output);
                                    msgbox('Special characters corrected.');
                                end
                            otherwise
                        end
                    end
                end
            end
        end

        % Button pushed function: CleardatatagsButton
        function CleardatatagsButtonPushed(app, event)
            app.data_tags = {};
            app.CurrentdatatagfileEditField.Value = "";
        end

        % Button pushed function: ImportsearchtagsButton
        function ImportsearchtagsButtonPushed(app, event)
            if not(isprop(app, 'search_vector') && ~isempty(app.search_vector))
                errordlg('To apply search tags you have to first import a search vector.','Error');
            else
                [f_search_tags,p_search_tags] = uigetfile({'*.txt','Text files';'*.csv','CSV';'*','All file types'});
                if f_search_tags == 0
                    return
                end
                if (ischar(p_search_tags))
                    search_tags_name = fullfile(p_search_tags,f_search_tags);
                else
                    search_tags_name = f_search_tags;
                end
                app.search_tags = importdata(search_tags_name,"\n");
                app.CurrentsearchtagfileEditField.Value=f_search_tags;
                if not(length(app.search_tags) == length(app.search_vector))
                    app.search_tags = {};
                    app.CurrentsearchtagfileEditField.Value = "";
                    errordlg('The search tags file has to be the same length as the search vector.','Error');
                end
                if ischar(app.search_tags)+isstring(app.search_tags)+iscell(app.search_tags)==0
                    app.search_tags = {};
                    app.CurrentsearchtagfileEditField.Value = "";
                    errordlg('The search tags has not been imported as a cell or string array.','Error');
                end
                size_search_tags = size(app.search_tags);
                if not(size_search_tags(2) == 1)
                    app.search_tags = {};
                    app.CurrentsearchtagfileEditField.Value = "";
                    errordlg('The search tags must be a vertical vector.','Error');
                end
                if app.DetectcorrectspecialcharactersininputfilesCheckBox.Value == 1
                    search_tags_spec_char_matrix = search_in_string(app,app.search_tags,app.spec_char_identifier);
                    if not(sum(search_tags_spec_char_matrix,"all") == 0)
                        search_spec_char_question = questdlg('It seems there are special characters in your search tags. Do you want to keep it, discard it, or try to correct it? (If your input file is a csv, please select "Keep", as it should not cause any issues)','Question','Keep','Discard','Correct','Keep');
                        switch search_spec_char_question
                            case 'Discard'
                                app.search_tags = {};
                                app.CurrentsearchvectorfileEditField.Value = "";
                                return
                            case 'Correct'
                                spec_char_correction_vector_input = {};
                                spec_char_correction_vector_output = {};
                                if app.LatincharactersCheckBox.Value + app.GreekcharactersCheckBox.Value + app.CyriliccharactersCheckBox.Value + app.GeneralsymbolsCheckBox.Value == 0
                                    msgbox("No special characters' type correction chosen. Search tags imported as-is.",'Warning','warn');
                                else
                                    if app.GeneralsymbolsCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_symbols);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_symbols);
                                    end
                                    if app.LatincharactersCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_latin);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_latin);
                                    end
                                    if app.GreekcharactersCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_greek);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_greek);
                                    end
                                    if app.CyriliccharactersCheckBox.Value == 1
                                        spec_char_correction_vector_input = vertcat(spec_char_correction_vector_input,app.spec_char_correction_vector_input_cyrilic);
                                        spec_char_correction_vector_output = vertcat(spec_char_correction_vector_output,app.spec_char_correction_vector_output_cyrilic);
                                    end
                                    app.search_tags = systemic_replacement(app,app.search_tags,spec_char_correction_vector_input,spec_char_correction_vector_output);
                                    msgbox('Special characters corrected.');
                                end
                            otherwise
                        end
                    end
                end
            end
        end

        % Button pushed function: ClearsearchtagsButton
        function ClearsearchtagsButtonPushed(app, event)
            app.search_tags = {};
            app.CurrentsearchtagfileEditField.Value = "";
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            !notepad help.txt
        end

        % Button pushed function: NormalizevectorsButton
        function NormalizevectorsButtonPushed(app, event)
            if not(isprop(app, 'data_vector') && ~isempty(app.data_vector))
                errordlg('No data vector imported.','Error');
            elseif not(isprop(app, 'search_vector') && ~isempty(app.search_vector))
                errordlg('No search vector imported.','Error');
            else
                if app.LowercasevectorsCheckBox.Value + app.NormalizediacriticsCheckBox.Value == 0
                    errordlg('No normalization options selected.','Error');
                    return
                else
                    if app.LowercasevectorsCheckBox.Value == 1
                        app.data_vector = lower(app.data_vector);
                        app.search_vector = lower(app.search_vector);
                        if app.NormalizediacriticsCheckBox.Value == 1
                            diacritic_normalization_input = app.diacritic_normalization_input_lowercase;
                            diacritic_normalization_output = app.diacritic_normalization_output_lowercase;
                            app.data_vector = systemic_replacement(app,app.data_vector,diacritic_normalization_input,diacritic_normalization_output);
                            app.search_vector = systemic_replacement(app,app.search_vector,diacritic_normalization_input,diacritic_normalization_output);
                        end
                    elseif app.NormalizediacriticsCheckBox.Value == 1
                        diacritic_normalization_input = vertcat(app.diacritic_normalization_input_lowercase,app.diacritic_normalization_input_uppercase);
                        diacritic_normalization_output = vertcat(app.diacritic_normalization_output_lowercase,app.diacritic_normalization_output_uppercase);
                        app.data_vector = systemic_replacement(app,app.data_vector,diacritic_normalization_input,diacritic_normalization_output);
                        app.search_vector = systemic_replacement(app,app.search_vector,diacritic_normalization_input,diacritic_normalization_output);
                    end
                    msgbox('Vectors normalized.');
                end
            end
        end

        % Button pushed function: SelectoutputfolderButton
        function SelectoutputfolderButtonPushed(app, event)
            app.output_path = uigetdir('C:\','Select output path');
            if app.output_path==0
                return
            else
                app.CurrentoutputdirectoryEditField.Value = string([char(app.output_path)]);
            end
        end

        % Button pushed function: RuncrosssearchButton
        function RuncrosssearchButtonPushed(app, event)
            if app.ExportverificationmatrixCheckBox.Value + app.ExportcombineddatamatrixwithtotalsCheckBox.Value + app.ExportcombinedsearchmatrixwithtotalsCheckBox.Value + app.ExporttotalsearchvaluesvectorpersearchpointCheckBox.Value + app.ExporttotalsearchvaluesvectorperdatapointCheckBox.Value + app.ExportfullmatrixCheckBox.Value == 0
                errordlg('You have not selected any output.','Error')
                return
            end
            output_name_conflicts = [string(app.VerificationmatrixfilenameEditField.Value);string(app.CombineddatamatrixfilenameEditField.Value);string(app.CombinedsearchmatrixfilenameEditField.Value);string(app.TotalsearchvaluesvectorpspfilenameEditField.Value);string(app.TotalsearchvaluesvectorpdpfilenameEditField.Value);string(app.FullmatrixfilenameEditField.Value)];
            if not(isprop(app, 'data_vector') && ~isempty(app.data_vector))
                errordlg('No data vector imported.','Error');
                return
            elseif not(isprop(app, 'search_vector') && ~isempty(app.search_vector))
                errordlg('No search vector imported.','Error');
                return
            elseif not(sum(size(output_name_conflicts))==sum(size(unique(output_name_conflicts))))
                errordlg('Two or more output files have the same name. (This message will appear regardless of whether or not the conflicting outputs have both been selected).','Error');
                return
            elseif not(isprop(app, 'output_path') && ~isempty(app.output_path))
                errordlg('No output folder specified.','Error');
                return
            elseif and(app.ExportfullmatrixCheckBox.Value == 1, length(app.data_vector) * length(app.search_vector) > 10000000)
                big_full_matrix_question = questdlg('The product of the lengths of your vectors exceeds 10 million. The generation of a full matrix is a very memory-intensive process, and it may fail or carsh the program. Are you sure you want to try and export a full matrix?','Warning','Yes','No','No');
                switch big_full_matrix_question
                    case 'Yes'
                    otherwise
                        app.ExportfullmatrixCheckBox.Value = 0;
                end
            end
            if app.ExportverificationmatrixCheckBox.Value + app.ExportcombineddatamatrixwithtotalsCheckBox.Value + app.ExportcombinedsearchmatrixwithtotalsCheckBox.Value + app.ExporttotalsearchvaluesvectorpersearchpointCheckBox.Value + app.ExporttotalsearchvaluesvectorperdatapointCheckBox.Value + app.ExportfullmatrixCheckBox.Value == 0
                errordlg('You have not selected any output.','Error')
                return
            else
                verification_matrix = search_in_string(app,app.data_vector,app.search_vector);
                if app.RemovefalsepositivesCheckBox.Value == 1
                    % I imagine the below code is rather suboptimal, I may
                    % try to improve it later, but for now it works and it
                    % is efficient enough
                    search_vector_squared_matrix = search_in_string(app,app.search_vector,app.search_vector);
                    search_vector_conflictmatrix = search_vector_squared_matrix - eye(length(app.search_vector));
                    number_of_conflicts_nostring = sum(search_vector_conflictmatrix,'all');
                    if number_of_conflicts_nostring ~= 0
                        [r_conflict,c_conflict] = find(search_vector_conflictmatrix);
                        length_conflict_entry_longer = zeros(size(r_conflict,1),1);
                        length_conflict_entry_shorter = zeros(size(c_conflict,1),1);
                        i_1 = 1;
                        % i_1 rotates through r_conflict and c_conflict
                        while i_1 <= size(r_conflict,1)
                            length_conflict_entry_longer(i_1) = length(char(app.search_vector(r_conflict(i_1))));
                            length_conflict_entry_shorter(i_1) = length(char(app.search_vector(c_conflict(i_1))));
                            i_1 = i_1 + 1;
                        end
                        matrix_false_positives_correction = horzcat(r_conflict,c_conflict,length_conflict_entry_longer,length_conflict_entry_shorter);
                        matrix_false_positives_correction = matrix_false_positives_correction(length_conflict_entry_longer > length_conflict_entry_shorter,:);
                        % The above line ensures that repeated search vector 
                        % entries (which are the only possible case of same length) 
                        % are excluded, as they are not false positives, and regardless, 
                        % can be removed with the 'Check seach vector conflicts/redundancies' button. 
                        % Length_conflict_entry_shorter can never be longer than length_conflict_entry_longer.
                        if ~isempty(matrix_false_positives_correction)
                            [~,~,X] = unique(matrix_false_positives_correction(:,2));
                            grouped_matrix_false_positives_correction = accumarray(X,1:size(matrix_false_positives_correction,1),[],@(r){matrix_false_positives_correction(r,:)});
                            % The above two lines of code was taken from Stephen Cobeldick (https://www.mathworks.com/matlabcentral/profile/authors/3102170)
                            % From https://www.mathworks.com/matlabcentral/answers/345111-splitting-matrix-based-on-a-value-in-one-column
                            i_2 = 1;
                            % i_2 rotates through grouped_matrix_false_positives_correction
                            while i_2 <= size(grouped_matrix_false_positives_correction,1)
                                search_of_conflict = grouped_matrix_false_positives_correction{i_2};
                                sortrows(search_of_conflict,3,'descend');
                                % This prevents incorrect errors during removal, where one replaces a substring that is in itself substring of another search vector,
                                % Which may lead to some possible false positives remaining and not being corrected.
                                r_possible_conflict = find(verification_matrix(:,search_of_conflict(1,2)));
                                i_3 = 1;
                                % i_3 rotates through temp_data_vector
                                if ~isempty(r_possible_conflict)
                                    temp_data_vector = app.data_vector(r_possible_conflict,1);
                                    while i_3 <= size(r_possible_conflict,1)
                                        data_entry = char(temp_data_vector(i_3));
                                        i_4 = 1;
                                        % i_4 rotates through search_of_conflict
                                            while i_4 <= size(search_of_conflict,1)
                                                data_entry = strrep(data_entry,char(app.search_vector(search_of_conflict(i_4,1),1)),'');
                                                i_4 = i_4 + 1;
                                            end
                                        temp_data_vector(i_3) = cellstr(data_entry);
                                        i_3 = i_3 + 1;
                                    end
                                    corrected_verification_matrix_column = search_in_string(app,temp_data_vector,app.search_vector(search_of_conflict(1,2)));
                                    verification_matrix(r_possible_conflict,search_of_conflict(1,2)) = corrected_verification_matrix_column;
                                else
                                end
                                i_2 = i_2 + 1;
                            end
                        else
                        end
                    else                    
                    end
                end
                datatotal = (sum(verification_matrix,2));
                searchtotal = (sum(verification_matrix))';
                if app.ExportverificationmatrixCheckBox.Value == 1
                    if isempty(char(app.VerificationmatrixfilenameEditField.Value)) == 1
                        errordlg('No name given to output file.','Error');
                        return
                    else
                        verification_matrix_output_name = string([char(app.output_path),'\',char(app.VerificationmatrixfilenameEditField.Value)]);
                        writematrix(verification_matrix,verification_matrix_output_name,'FileType','text');
                    end
                end
                if app.ExportcombineddatamatrixwithtotalsCheckBox.Value == 1
                    if isempty(char(app.CombineddatamatrixfilenameEditField.Value)) == 1
                        errordlg('No name given to output file.','Error');
                        return
                    else
                        if (isprop(app, 'data_tags') && ~isempty(app.data_tags))
                            combined_data_matrix = [string(app.data_tags),string(app.data_vector),string(datatotal)];
                        else
                            combined_data_matrix = [string(app.data_vector),string(datatotal)];
                        end
                        combined_data_matrix_output_name = string([char(app.output_path),'\',char(app.CombineddatamatrixfilenameEditField.Value)]);
                        writematrix(combined_data_matrix,combined_data_matrix_output_name,'FileType','spreadsheet');
                    end
                end
                if app.ExportcombinedsearchmatrixwithtotalsCheckBox.Value == 1
                    if isempty(char(app.CombinedsearchmatrixfilenameEditField.Value)) == 1
                        errordlg('No name given to output file.','Error');
                        return
                    else
                        if (isprop(app, 'search_tags') && ~isempty(app.search_tags))
                            combined_search_matrix = [string(app.search_tags),string(app.search_vector),string(searchtotal)];
                        else
                            combined_search_matrix = [string(app.search_vector),string(searchtotal)];
                        end
                        combined_search_matrix_output_name = string([char(app.output_path),'\',char(app.CombinedsearchmatrixfilenameEditField.Value)]);
                        writematrix(combined_search_matrix,combined_search_matrix_output_name,'FileType','spreadsheet');
                    end
                end
                if app.ExporttotalsearchvaluesvectorpersearchpointCheckBox.Value == 1
                    if isempty(char(app.TotalsearchvaluesvectorpspfilenameEditField.Value)) == 1
                        errordlg('No name given to output file.','Error');
                        return
                    else
                        search_value_vector_psp_output_name = string([char(app.output_path),'\',char(app.TotalsearchvaluesvectorpspfilenameEditField.Value)]);
                        writematrix(searchtotal,search_value_vector_psp_output_name,'FileType','text');
                    end
                end
                if app.ExporttotalsearchvaluesvectorperdatapointCheckBox.Value == 1
                    if isempty(char(app.TotalsearchvaluesvectorpdpfilenameEditField.Value)) == 1
                        errordlg('No name given to output file.','Error');
                        return
                    else
                        search_value_vector_pdp_output_name = string([char(app.output_path),'\',char(app.TotalsearchvaluesvectorpdpfilenameEditField.Value)]);
                        writematrix(datatotal,search_value_vector_pdp_output_name,'FileType','text');
                    end
                end
                if app.ExportfullmatrixCheckBox.Value == 1
                    if isempty(char(app.FullmatrixfilenameEditField.Value)) == 1
                        errordlg('No name given to output file.','Error');
                        return
                    else
                        upper_left_corner_blanks = {[""]};
                        if (isprop(app, 'data_tags') && ~isempty(app.data_tags))
                            upper_left_corner_blanks = horzcat(upper_left_corner_blanks,upper_left_corner_blanks);
                            data_column = horzcat(app.data_tags,app.data_vector);
                            data_column = vertcat(data_column,{[""],["Sum per search point"]});
                        else
                            data_column = vertcat(app.data_vector,{["Sum per search point"]});
                        end
                        if (isprop(app, 'search_tags') && ~isempty(app.search_tags))
                            upper_left_corner_blanks = vertcat (upper_left_corner_blanks,upper_left_corner_blanks);
                            search_row = vertcat(app.search_tags',app.search_vector');
                            search_row = horzcat(search_row,{[""];["Sum per data point"]});
                        else
                            search_row = horzcat(app.search_vector',{["Sum per data point"]});
                        end
                        full_matrix_output_name = string([char(app.output_path),'\',char(app.FullmatrixfilenameEditField.Value)]);
                        full_matrix_top_row = horzcat(upper_left_corner_blanks,search_row);
                        full_matrix_body = vertcat(num2cell(verification_matrix),num2cell(searchtotal'));
                        full_matrix_body = horzcat(full_matrix_body,vertcat(num2cell(datatotal),{[""]}));
                        full_matrix_body = horzcat(data_column,full_matrix_body);
                        full_matrix = vertcat(full_matrix_top_row,full_matrix_body);
                        if app.OnlysearchxdatasheetButton.Value == 0
                            writecell(full_matrix,full_matrix_output_name,'FileType','spreadsheet','Sheet','data_x_search');
                        end
                        if app.OnlydataxsearchsheetButton.Value == 0
                            writecell(full_matrix',full_matrix_output_name,'FileType','spreadsheet','Sheet','search_x_data');
                        end
                    end
                end
                msgbox('Cross-search completed');
            end
        end

        % Value changed function: ExportfullmatrixCheckBox
        function ExportfullmatrixCheckBoxValueChanged(app, event)
            value = app.ExportfullmatrixCheckBox.Value;
            if value == 0
                set(app.OnlydataxsearchsheetButton,'Enable','off')
                set(app.OnlysearchxdatasheetButton,'Enable','off')
                set(app.BothsheetsButton,'Enable','off')
            else
                set(app.OnlydataxsearchsheetButton,'Enable','on')
                set(app.OnlysearchxdatasheetButton,'Enable','on')
                set(app.BothsheetsButton,'Enable','on')
            end
        end

        % Callback function
        function ImportmultiplevectorfilessimultaneouslyCheckBoxValueChanged(app, event)
            value = app.ImportmultiplevectorfilessimultaneouslyCheckBox.Value;
            if value == 0
                set(app.AskButton,'Enable','on');
            else
                set(app.AskButton,'Enable','off');
            end
        end

        % Callback function
        function DebugButtonPushed(app, event)
            writecell(app.data_vector,'debug.xlsx','Sheet','datavector');
            writecell(app.search_vector,'debug.xlsx','Sheet','searchvector');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.StringCrossSearchAppUIFigure = uifigure('Visible', 'off');
            app.StringCrossSearchAppUIFigure.Name = 'String Cross-Search App';
            app.DevelopedbyBagratAbramianPetrosianusingMATLABR2019b2020Label = uilabel(app.StringCrossSearchAppUIFigure);
            app.DevelopedbyBagratAbramianPetrosianusingMATLABR2019b2020Label.Text = 'Developed by Bagrat Abramian Petrosian, using MATLAB R2019b, 2020';
            app.v12Label = uilabel(app.StringCrossSearchAppUIFigure);
            app.v12Label.Text = 'v 1.2';
            app.TabGroup = uitabgroup(app.StringCrossSearchAppUIFigure);
            app.BasicOptionsTab = uitab(app.TabGroup);
            app.BasicOptionsTab.Title = 'Basic Options';
            app.NormalizevectorsButton = uibutton(app.BasicOptionsTab, 'push');
            app.NormalizevectorsButton.ButtonPushedFcn = createCallbackFcn(app, @NormalizevectorsButtonPushed, true);
            app.NormalizevectorsButton.Tooltip = {'Normalizes the data and search vectors according to the "Normalization options" in the "Advanced Options" tab.'};
            app.NormalizevectorsButton.Text = 'Normalize vectors';
            app.RuncrosssearchButton = uibutton(app.BasicOptionsTab, 'push');
            app.RuncrosssearchButton.ButtonPushedFcn = createCallbackFcn(app, @RuncrosssearchButtonPushed, true);
            app.RuncrosssearchButton.Tooltip = {'Executes the cross-search of the search vector in the data vector, and generates all the output files chosen.'};
            app.RuncrosssearchButton.Text = 'Run cross-search';
            app.SelectoutputfolderButton = uibutton(app.BasicOptionsTab, 'push');
            app.SelectoutputfolderButton.ButtonPushedFcn = createCallbackFcn(app, @SelectoutputfolderButtonPushed, true);
            app.SelectoutputfolderButton.Tooltip = {'Designates the folder in which the output file(s) will be saved.'};
            app.SelectoutputfolderButton.Text = 'Select output folder';
            app.HelpButton = uibutton(app.BasicOptionsTab, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Tooltip = {'Opens "help.txt".'};
            app.HelpButton.Text = 'Help';
            app.CurrentdatavectorfileEditFieldLabel = uilabel(app.BasicOptionsTab);
            app.CurrentdatavectorfileEditFieldLabel.Text = 'Current data vector file';
            app.CurrentdatavectorfileEditField = uieditfield(app.BasicOptionsTab, 'text');
            app.CurrentdatavectorfileEditField.Editable = 'off';
            app.CurrentdatavectorfileEditField.Tooltip = {'Currently loaded data vector.'};
            app.CurrentsearchvectorfileEditFieldLabel = uilabel(app.BasicOptionsTab);
            app.CurrentsearchvectorfileEditFieldLabel.Text = 'Current search vector file';
            app.CurrentsearchvectorfileEditField = uieditfield(app.BasicOptionsTab, 'text');
            app.CurrentsearchvectorfileEditField.Editable = 'off';
            app.CurrentsearchvectorfileEditField.Tooltip = {'Currently loaded search vector.'};
            app.CurrentoutputdirectoryEditFieldLabel = uilabel(app.BasicOptionsTab);
            app.CurrentoutputdirectoryEditFieldLabel.Text = 'Current output directory';
            app.CurrentoutputdirectoryEditField = uieditfield(app.BasicOptionsTab, 'text');
            app.CurrentoutputdirectoryEditField.Editable = 'off';
            app.CurrentoutputdirectoryEditField.Tooltip = {'Direction where the output files will be created.'};
            app.CurrentdatatagfileEditFieldLabel = uilabel(app.BasicOptionsTab);
            app.CurrentdatatagfileEditFieldLabel.Text = 'Current data tag file';
            app.CurrentdatatagfileEditField = uieditfield(app.BasicOptionsTab, 'text');
            app.CurrentdatatagfileEditField.Editable = 'off';
            app.CurrentdatatagfileEditField.Tooltip = {'Currently loaded data tags.'};
            app.CurrentsearchtagfileEditFieldLabel = uilabel(app.BasicOptionsTab);
            app.CurrentsearchtagfileEditFieldLabel.Text = 'Current search tag file';
            app.CurrentsearchtagfileEditField = uieditfield(app.BasicOptionsTab, 'text');
            app.CurrentsearchtagfileEditField.Editable = 'off';
            app.CurrentsearchtagfileEditField.Tooltip = {'Currently loaded search tags.'};
            app.ImportoptionsPanel = uipanel(app.BasicOptionsTab);
            app.ImportoptionsPanel.Title = 'Import options';
            app.DetectcorrectspecialcharactersininputfilesCheckBox = uicheckbox(app.ImportoptionsPanel);
            app.DetectcorrectspecialcharactersininputfilesCheckBox.Tooltip = {'This option allows the program to check when importing the data vector, search vector, data tags, or search tags, whether or not they contain special characters which may have been imported incorrectly.'};
            app.DetectcorrectspecialcharactersininputfilesCheckBox.Text = {'Detect/correct special '; 'characters in input files'};
            app.ImportdatavectrorButton = uibutton(app.ImportoptionsPanel, 'push');
            app.ImportdatavectrorButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdatavectrorButtonPushed, true);
            app.ImportdatavectrorButton.Tooltip = {'Imports the data in which you want to search the various substrings.'; 'The source file must be in the form of a vertical vector.'};
            app.ImportdatavectrorButton.Text = 'Import data vectror';
            app.ImportsearchvectorButton = uibutton(app.ImportoptionsPanel, 'push');
            app.ImportsearchvectorButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsearchvectorButtonPushed, true);
            app.ImportsearchvectorButton.Tooltip = {'Imports the list of substrings to be searched in the data vector.'; 'The source file must be in the form of a vertical vector.'};
            app.ImportsearchvectorButton.Text = 'Import search vector';
            app.ImportdatatagsButton = uibutton(app.ImportoptionsPanel, 'push');
            app.ImportdatatagsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportdatatagsButtonPushed, true);
            app.ImportdatatagsButton.Tooltip = {'Imports a file to serve as tags for each of the entries of the data vector.'; 'The data tags do not affect the search, but are printed when exporting as "Export combined data matrix with totals" and "Full matrix".'};
            app.ImportdatatagsButton.Text = 'Import data tags';
            app.CleardatatagsButton = uibutton(app.ImportoptionsPanel, 'push');
            app.CleardatatagsButton.ButtonPushedFcn = createCallbackFcn(app, @CleardatatagsButtonPushed, true);
            app.CleardatatagsButton.Tooltip = {'Removes the current data tags loaded, so as to keep it from appearing in the export files.'};
            app.CleardatatagsButton.Text = 'Clear data tags';
            app.ImportsearchtagsButton = uibutton(app.ImportoptionsPanel, 'push');
            app.ImportsearchtagsButton.ButtonPushedFcn = createCallbackFcn(app, @ImportsearchtagsButtonPushed, true);
            app.ImportsearchtagsButton.Tooltip = {'Imports a file to serve as tags for each of the entries of the search vector.'; 'The data tags do not affect the search, but are printed when exporting as "Export combined search matrix with totals" and "Full matrix".'};
            app.ImportsearchtagsButton.Text = 'Import search tags';
            app.ClearsearchtagsButton = uibutton(app.ImportoptionsPanel, 'push');
            app.ClearsearchtagsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsearchtagsButtonPushed, true);
            app.ClearsearchtagsButton.Tooltip = {'Removes the current search tags loaded, so as to keep it from appearing in the export files.'};
            app.ClearsearchtagsButton.Text = 'Clear search tags';
            app.CleardatavectorButton = uibutton(app.ImportoptionsPanel, 'push');
            app.CleardatavectorButton.ButtonPushedFcn = createCallbackFcn(app, @CleardatavectorButtonPushed, true);
            app.CleardatavectorButton.Text = 'Clear data vector';
            app.ClearsearchvectorButton = uibutton(app.ImportoptionsPanel, 'push');
            app.ClearsearchvectorButton.ButtonPushedFcn = createCallbackFcn(app, @ClearsearchvectorButtonPushed, true);
            app.ClearsearchvectorButton.Text = 'Clear search vector';
            app.ExportoptionsPanel = uipanel(app.BasicOptionsTab);
            app.ExportoptionsPanel.Title = 'Export options';
            app.ExporttotalsearchvaluesvectorperdatapointCheckBox = uicheckbox(app.ExportoptionsPanel);
            app.ExporttotalsearchvaluesvectorperdatapointCheckBox.Tooltip = {'Upon run, creates a text file with a vertical vector for the number of substrings found in each string.'};
            app.ExporttotalsearchvaluesvectorperdatapointCheckBox.Text = 'Export total search values vector (per data point)';
            app.ExporttotalsearchvaluesvectorpersearchpointCheckBox = uicheckbox(app.ExportoptionsPanel);
            app.ExporttotalsearchvaluesvectorpersearchpointCheckBox.Tooltip = {'Upon run, creates a text file with a vertical vector for the number of strings in which the respective substring is found.'};
            app.ExporttotalsearchvaluesvectorpersearchpointCheckBox.Text = 'Export total search values vector (per search point)';
            app.ExportcombineddatamatrixwithtotalsCheckBox = uicheckbox(app.ExportoptionsPanel);
            app.ExportcombineddatamatrixwithtotalsCheckBox.Tooltip = {'Upon run, creates a xls file with the first column being the data vector, and the second will be the number of substrings found in each string.'};
            app.ExportcombineddatamatrixwithtotalsCheckBox.Text = 'Export combined data matrix with totals';
            app.ExportcombinedsearchmatrixwithtotalsCheckBox = uicheckbox(app.ExportoptionsPanel);
            app.ExportcombinedsearchmatrixwithtotalsCheckBox.Tooltip = {'Upon run, creates a xls file with the first column being the search vector, and the second will be the number of strings in which the respective substring is found.'};
            app.ExportcombinedsearchmatrixwithtotalsCheckBox.Text = 'Export combined search matrix with totals';
            app.TotalsearchvaluesvectorpdpfilenameEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.TotalsearchvaluesvectorpdpfilenameEditFieldLabel.Text = 'Total search values vector (pdp) filename';
            app.TotalsearchvaluesvectorpdpfilenameEditField = uieditfield(app.ExportoptionsPanel, 'text');
            app.TotalsearchvaluesvectorpdpfilenameEditField.Value = 'Data vector totals';
            app.TotalsearchvaluesvectorpspfilenameEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.TotalsearchvaluesvectorpspfilenameEditFieldLabel.Text = 'Total search values vector (psp) filename';
            app.TotalsearchvaluesvectorpspfilenameEditField = uieditfield(app.ExportoptionsPanel, 'text');
            app.TotalsearchvaluesvectorpspfilenameEditField.Value = 'Search vector totals';
            app.CombineddatamatrixfilenameEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.CombineddatamatrixfilenameEditFieldLabel.Text = 'Combined data matrix filename';
            app.CombineddatamatrixfilenameEditField = uieditfield(app.ExportoptionsPanel, 'text');
            app.CombineddatamatrixfilenameEditField.Value = 'Data matrix';
            app.CombinedsearchmatrixfilenameEditFieldLabel = uilabel(app.ExportoptionsPanel);
            app.CombinedsearchmatrixfilenameEditFieldLabel.Text = 'Combined search matrix filename';
            app.CombinedsearchmatrixfilenameEditField = uieditfield(app.ExportoptionsPanel, 'text');
            app.CombinedsearchmatrixfilenameEditField.Value = 'Search matrix';
            app.SearchvectorconflictsPanel = uipanel(app.BasicOptionsTab);
            app.SearchvectorconflictsPanel.Title = 'Search vector conflicts';
            app.CheckseachvectorconflictsredundanciesButton = uibutton(app.SearchvectorconflictsPanel, 'push');
            app.CheckseachvectorconflictsredundanciesButton.ButtonPushedFcn = createCallbackFcn(app, @CheckseachvectorconflictsredundanciesButtonPushed, true);
            app.CheckseachvectorconflictsredundanciesButton.Tooltip = {'Searches whether any of the substrings of the search vector is also a substring of any other substring.'};
            app.CheckseachvectorconflictsredundanciesButton.Text = {'Check seach vector '; 'conflicts/redundancies'};
            app.SearchvectorconflictsTextArea = uitextarea(app.SearchvectorconflictsPanel);
            app.SearchvectorconflictsTextArea.Editable = 'off';
            app.SearchvectorconflictsTextArea.Tooltip = {'Lists the results of the "Check search vector conflicts" operation.'};
            app.UponidenticalsearchentriesButtonGroup = uibuttongroup(app.SearchvectorconflictsPanel);
            app.UponidenticalsearchentriesButtonGroup.Tooltip = {'Determine the behavior of the ''Check search vector conflicts/redundancies'' upon finding identical entries in the search vector'};
            app.UponidenticalsearchentriesButtonGroup.Title = 'Upon identical search entries';
            app.KeepallsearchentriesButton = uiradiobutton(app.UponidenticalsearchentriesButtonGroup);
            app.KeepallsearchentriesButton.Tooltip = {'Preserve the search vector as-is'};
            app.KeepallsearchentriesButton.Text = 'Keep all search entries';
            app.KeepallsearchentriesButton.Value = true;
            app.KeeponlyfirstButton = uiradiobutton(app.UponidenticalsearchentriesButtonGroup);
            app.KeeponlyfirstButton.Tooltip = {'When several search vector entries are identical, keep only the first one'};
            app.KeeponlyfirstButton.Text = 'Keep only first';
            app.KeeponlylastButton = uiradiobutton(app.UponidenticalsearchentriesButtonGroup);
            app.KeeponlylastButton.Tooltip = {'When several search vector entries are identical, keep only the last one'};
            app.KeeponlylastButton.Text = 'Keep only last';
            app.AdvancedOptionsTab = uitab(app.TabGroup);
            app.AdvancedOptionsTab.Title = 'Advanced Options';
            app.OtherexportoptionsPanel = uipanel(app.AdvancedOptionsTab);
            app.OtherexportoptionsPanel.Tooltip = {''};
            app.OtherexportoptionsPanel.Title = 'Other export options';
            app.ExportverificationmatrixCheckBox = uicheckbox(app.OtherexportoptionsPanel);
            app.ExportverificationmatrixCheckBox.Tooltip = {'Upon run, creates a text file with the comma-separated correlation matrix which describes precisely which substrings are found in each string.'};
            app.ExportverificationmatrixCheckBox.Text = 'Export verification matrix (not recomended)';
            app.ExportfullmatrixCheckBox = uicheckbox(app.OtherexportoptionsPanel);
            app.ExportfullmatrixCheckBox.ValueChangedFcn = createCallbackFcn(app, @ExportfullmatrixCheckBoxValueChanged, true);
            app.ExportfullmatrixCheckBox.Tooltip = {'Upon run, creates a xls file with 2 sheets, which contain all the information of all other output options.'; ''};
            app.ExportfullmatrixCheckBox.Text = 'Export full matrix (not recomended)';
            app.VerificationmatrixfilenameEditFieldLabel = uilabel(app.OtherexportoptionsPanel);
            app.VerificationmatrixfilenameEditFieldLabel.Text = 'Verification matrix filename';
            app.VerificationmatrixfilenameEditField = uieditfield(app.OtherexportoptionsPanel, 'text');
            app.VerificationmatrixfilenameEditField.Value = 'Verification matrix';
            app.FullmatrixfilenameEditFieldLabel = uilabel(app.OtherexportoptionsPanel);
            app.FullmatrixfilenameEditFieldLabel.Text = 'Full matrix filename';
            app.FullmatrixfilenameEditField = uieditfield(app.OtherexportoptionsPanel, 'text');
            app.FullmatrixfilenameEditField.Value = 'Full matrix';
            app.FullmatrixexportoptionsButtonGroup = uibuttongroup(app.OtherexportoptionsPanel);
            app.FullmatrixexportoptionsButtonGroup.Title = 'Full matrix export options';
            app.OnlydataxsearchsheetButton = uiradiobutton(app.FullmatrixexportoptionsButtonGroup);
            app.OnlydataxsearchsheetButton.Enable = 'off';
            app.OnlydataxsearchsheetButton.Tooltip = {'Export only a full matrix with the data vector''s entries as rows and the search vector''s entries as columns.'};
            app.OnlydataxsearchsheetButton.Text = 'Only data x search sheet';
            app.OnlysearchxdatasheetButton = uiradiobutton(app.FullmatrixexportoptionsButtonGroup);
            app.OnlysearchxdatasheetButton.Enable = 'off';
            app.OnlysearchxdatasheetButton.Tooltip = {'Export only a full matrix with the search vector''s entries as rows and the data vector''s entries as columns.'};
            app.OnlysearchxdatasheetButton.Text = 'Only search x data sheet';
            app.BothsheetsButton = uiradiobutton(app.FullmatrixexportoptionsButtonGroup);
            app.BothsheetsButton.Enable = 'off';
            app.BothsheetsButton.Text = 'Both sheets';
            app.BothsheetsButton.Value = true;
            app.NormalizationoptionsPanel = uipanel(app.AdvancedOptionsTab);
            app.NormalizationoptionsPanel.Tooltip = {'Selects which type of normalization will be performed when using "Normalize vectors".'};
            app.NormalizationoptionsPanel.Title = 'Normalization options';
            app.LowercasevectorsCheckBox = uicheckbox(app.NormalizationoptionsPanel);
            app.LowercasevectorsCheckBox.Tooltip = {'Normalization turns all characters'' case into lowercase.'};
            app.LowercasevectorsCheckBox.Text = 'Lowercase vectors';
            app.LowercasevectorsCheckBox.Value = true;
            app.NormalizediacriticsCheckBox = uicheckbox(app.NormalizationoptionsPanel);
            app.NormalizediacriticsCheckBox.Tooltip = {'Changes various letters with diacritics into the same letters without them.'};
            app.NormalizediacriticsCheckBox.Text = 'Normalize diacritics';
            app.SpecialcharacterscorrectionoptionsPanel = uipanel(app.AdvancedOptionsTab);
            app.SpecialcharacterscorrectionoptionsPanel.Tooltip = {'Selects which types of characters will be corrected if special characters are detected and "Correct" is selected (see 1.1.1).'; 'The more options selected, the longer the correction will take.'};
            app.SpecialcharacterscorrectionoptionsPanel.Title = 'Special characters'' correction options';
            app.LatincharactersCheckBox = uicheckbox(app.SpecialcharacterscorrectionoptionsPanel);
            app.LatincharactersCheckBox.Text = 'Latin characters';
            app.LatincharactersCheckBox.Value = true;
            app.GreekcharactersCheckBox = uicheckbox(app.SpecialcharacterscorrectionoptionsPanel);
            app.GreekcharactersCheckBox.Text = 'Greek characters';
            app.CyriliccharactersCheckBox = uicheckbox(app.SpecialcharacterscorrectionoptionsPanel);
            app.CyriliccharactersCheckBox.Text = 'Cyrilic characters';
            app.GeneralsymbolsCheckBox = uicheckbox(app.SpecialcharacterscorrectionoptionsPanel);
            app.GeneralsymbolsCheckBox.Text = 'General symbols';
            app.GeneralsymbolsCheckBox.Value = true;
            app.AdvancedcrosssearchoptionsPanel = uipanel(app.AdvancedOptionsTab);
            app.AdvancedcrosssearchoptionsPanel.Title = 'Advanced cross-search options';
            app.RemovefalsepositivesCheckBox = uicheckbox(app.AdvancedcrosssearchoptionsPanel);
            app.RemovefalsepositivesCheckBox.Tooltip = {'Removes positive results for search entries when they are only found within other search entries'};
            app.RemovefalsepositivesCheckBox.Text = 'Remove false positives';
            % Show the figure after all components are created
            app.StringCrossSearchAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = StringCrossSearchApp_v12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.StringCrossSearchAppUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.StringCrossSearchAppUIFigure)
        end
    end
end